classdef CWTBandpassApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CWTBandpassUIFigure           matlab.ui.Figure
        PreviewPanel                  matlab.ui.container.Panel
        PreviewWindowSizeEditField    matlab.ui.control.EditField
        PreviewWindowSizeEditFieldLabel  matlab.ui.control.Label
        FrequencyAdjustmentPanel      matlab.ui.container.Panel
        LowerFrequencyEditField       matlab.ui.control.EditField
        LowerFrequencyEditFieldLabel  matlab.ui.control.Label
        UpperFrequencyEditField       matlab.ui.control.EditField
        UpperFrequencyEditFieldLabel  matlab.ui.control.Label
        FilterButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TonaFlowApp 
        filteredECG_Y % Changes dynamically
        comp % Composite CWT
        cfs
        f
        bounds
        ECG_Y
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.TonaFlowApp = mainapp;
            app.ECG_Y = app.TonaFlowApp.ECG_Y - mean(app.TonaFlowApp.ECG_Y);


            plot(app.UIAxes, app.TonaFlowApp.ECG_X, app.ECG_Y,"Color",[0 0.4470 0.7410 .4])
            hold(app.UIAxes,'on')

            % Calculate CWT
            [app.cfs, app.f] = cwt(app.ECG_Y,'bump',150,'VoicesPerOctive',32);
            app.bounds = [min(app.f) max(app.f)];
            app.comp=icwt(app.cfs,[],app.f,app.bounds);
            
            plot(app.UIAxes, app.TonaFlowApp.ECG_X, app.comp,'Color',[0.8500 0.3250 0.0980],'LineWidth',3)

            % Fill in the bounds boxes with their respectives 
            app.UpperFrequencyEditField.Value = string(app.bounds(2));
            app.LowerFrequencyEditField.Value = string(app.bounds(1));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CWTBandpassUIFigure = uifigure('Visible', 'off');
            app.CWTBandpassUIFigure.Name = 'CWT Bandpass';
            app.UIAxes = uiaxes(app.CWTBandpassUIFigure);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [];
            app.CancelButton = uibutton(app.CWTBandpassUIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.FilterButton = uibutton(app.CWTBandpassUIFigure, 'push');
            app.FilterButton.Text = 'Filter';
            app.FrequencyAdjustmentPanel = uipanel(app.CWTBandpassUIFigure);
            app.FrequencyAdjustmentPanel.Title = 'Frequency Adjustment';
            app.UpperFrequencyEditFieldLabel = uilabel(app.FrequencyAdjustmentPanel);
            app.UpperFrequencyEditFieldLabel.Text = 'Upper Frequency';
            app.UpperFrequencyEditField = uieditfield(app.FrequencyAdjustmentPanel, 'text');
            app.LowerFrequencyEditFieldLabel = uilabel(app.FrequencyAdjustmentPanel);
            app.LowerFrequencyEditFieldLabel.Text = 'Lower Frequency';
            app.LowerFrequencyEditField = uieditfield(app.FrequencyAdjustmentPanel, 'text');
            app.PreviewPanel = uipanel(app.CWTBandpassUIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.PreviewWindowSizeEditFieldLabel = uilabel(app.PreviewPanel);
            app.PreviewWindowSizeEditFieldLabel.Text = 'Preview Window Size';
            app.PreviewWindowSizeEditField = uieditfield(app.PreviewPanel, 'text');
            % Show the figure after all components are created
            app.CWTBandpassUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CWTBandpassApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CWTBandpassUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CWTBandpassUIFigure)
        end
    end
end