classdef RespDLG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RespiratorySignalExtractionUIFigure  matlab.ui.Figure
        FrequencyAdjustmentPanel        matlab.ui.container.Panel
        LowerFrequencyHzEditField       matlab.ui.control.NumericEditField
        LowerFrequencyHzEditFieldLabel  matlab.ui.control.Label
        UpperFrequencyHzEditField       matlab.ui.control.NumericEditField
        UpperFrequencyHzEditFieldLabel  matlab.ui.control.Label
        NoteUsetheYAdjusttoadLabel      matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        YAdjustSlider                   matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tonaflowApp % main tonaflow
        ECG_Norm % Normalized ECG
        bounds
        comp
        frqLims
        cfs
        f
    end
    
    methods (Access = private)
        
        function results = CalculateRespiration(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.tonaflowApp = mainapp;
            
            % tell the user that this may take a while
            waitbar = uiprogressdlg(mainapp.UIFigure,'Message','Calculating CWT... This may take a while!');

            % Normalize the ECG Signal for easier visualization
            app.ECG_Norm = [app.tonaflowApp.ECG.X_Raw, app.tonaflowApp.ECG.Y_Raw - mean(app.tonaflowApp.ECG.Y_Raw)];
            % Set Lims for the slider
            lim = [min(app.ECG_Norm(:,2)) max(app.ECG_Norm(:,2))];
            app.YAdjustSlider.Limits = lim;
            app.YAdjustSlider.Value = sum(lim)/2;
            
            %% CWT
            [app.cfs, app.f] = cwt(app.ECG_Norm(:,2),'bump',150,'VoicesPerOctive',32);
            % Obtain the limits to the CWT 
            lowerBound = min(app.f);
            upperBound = max(app.f);
            app.frqLims = [lowerBound upperBound];
            app.bounds = [lowerBound upperBound];
            % set the spinners to these values
            app.LowerFrequencyHzEditField.Value = lowerBound;
            app.UpperFrequencyHzEditField.Value = upperBound;
            % calculate the inverse CWT to obtain the frequencies within
            % those bounds
            app.comp=icwt(app.cfs,[],app.f,app.bounds);
            %% Plot
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.ECG_Norm(:,1),app.ECG_Norm(:,2),'Color',[.1 .5 .5 .4]);
            plot(app.UIAxes,app.ECG_Norm(:,1),app.comp,'LineWidth',3)

            uialert(app.RespiratorySignalExtractionUIFigure,"Warning! Respiration signal extraction from ECG is experimental. Use with caution!","Warning! Experimental tool!")
            % Close the waitbar
            close(waitbar);
        end

        % Value changed function: UpperFrequencyHzEditField
        function UpperFrequencyHzEditFieldValueChanged(app, event)
            value = app.UpperFrequencyHzEditField.Value;
            if value > app.frqLims(2)
                app.bounds(2) = app.frqLims(2);
                app.UpperFrequencyHzEditField.Value = app.frqLims(2);
                value = app.bounds(2);
            else
                app.bounds(2) = value;
            end

            % Create wait bar
            waitbar = uiprogressdlg(app.RespiratorySignalExtractionUIFigure,'Message','Recalculating iCWT...');
            % recalculate app.comp
            app.comp=icwt(app.cfs,[],app.f,app.bounds);

            % Update the plot 
            app.UIAxes.Children(1).YData = app.comp + app.YAdjustSlider.Value;

            % close waitbar
            close(waitbar);
        end

        % Value changed function: LowerFrequencyHzEditField
        function LowerFrequencyHzEditFieldValueChanged(app, event)
            value = app.LowerFrequencyHzEditField.Value;
            if value < app.frqLims(1)
                app.bounds(1) = app.frqLims(1);
                app.LowerFrequencyHzEditField.Value = app.frqLims(1);
                value = app.bounds(1);
            else
                app.bounds(1) = value;
            end
            % Create wait bar
            waitbar = uiprogressdlg(app.RespiratorySignalExtractionUIFigure,'Message','Recalculating iCWT...');

            % recalculate app.comp
            app.comp=icwt(app.cfs,[],app.f,app.bounds);

            % Update the plot 
            app.UIAxes.Children(1).YData = app.comp + app.YAdjustSlider.Value;
             % close waitbar
            close(waitbar);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            waitbar = uiprogressdlg(app.RespiratorySignalExtractionUIFigure,'message','Calculating Respiration Rate...');
            app.tonaflowApp.ECG = app.tonaflowApp.ECG.CalculateRespirationRate(app.tonaflowApp.ECG.X_Raw,app.comp,app.tonaflowApp.ECG.Fs);
            
            %Plot to tonaflow
            % app.tonaflowApp.RespRate_Plot = plot(app.tonaflowApp.RespRate_Axis,app.tonaflowApp.ECG.Resp_X,app.tonaflowApp.ECG.Resp_Y, app.tonaflowApp.Resp_Color{:});
            app.tonaflowApp.ReplotRespRate;
            close(waitbar)
            delete(app)
        end

        % Value changing function: YAdjustSlider
        function YAdjustSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.Children(1).YData = app.comp + changingValue;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RespiratorySignalExtractionUIFigure = uifigure('Visible', 'off');
            app.RespiratorySignalExtractionUIFigure.Name = 'Respiratory Signal Extraction';
            app.UIAxes = uiaxes(app.RespiratorySignalExtractionUIFigure);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.YAdjustSlider = uislider(app.RespiratorySignalExtractionUIFigure);
            app.YAdjustSlider.ValueChangingFcn = createCallbackFcn(app, @YAdjustSliderValueChanging, true);
            app.RunButton = uibutton(app.RespiratorySignalExtractionUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.RespiratorySignalExtractionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NoteUsetheYAdjusttoadLabel = uilabel(app.RespiratorySignalExtractionUIFigure);
            app.NoteUsetheYAdjusttoadLabel.Text = 'Note: Use the Y Adjust to adjust the resulting CWT and determine goodness of fit. If you cannot see a similarity, the respiration signal does not exist. ';
            app.FrequencyAdjustmentPanel = uipanel(app.RespiratorySignalExtractionUIFigure);
            app.FrequencyAdjustmentPanel.Title = 'Frequency Adjustment';
            app.UpperFrequencyHzEditFieldLabel = uilabel(app.FrequencyAdjustmentPanel);
            app.UpperFrequencyHzEditFieldLabel.Text = 'Upper Frequency (Hz)';
            app.UpperFrequencyHzEditField = uieditfield(app.FrequencyAdjustmentPanel, 'numeric');
            app.UpperFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @UpperFrequencyHzEditFieldValueChanged, true);
            app.LowerFrequencyHzEditFieldLabel = uilabel(app.FrequencyAdjustmentPanel);
            app.LowerFrequencyHzEditFieldLabel.Text = 'Lower Frequency (Hz)';
            app.LowerFrequencyHzEditField = uieditfield(app.FrequencyAdjustmentPanel, 'numeric');
            app.LowerFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowerFrequencyHzEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.RespiratorySignalExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RespDLG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RespiratorySignalExtractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RespiratorySignalExtractionUIFigure)
        end
    end
end