classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadProjectFileMenu           matlab.ui.container.Menu
        SaveProjectFileMenu           matlab.ui.container.Menu
        LoadECGMenu                   matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        AllContentsMenu               matlab.ui.container.Menu
        ECGMenu                       matlab.ui.container.Menu
        FilterwithCWTMenu             matlab.ui.container.Menu
        ExtractRespiratorySignalMenu  matlab.ui.container.Menu
        CalculateHeartRateMenu        matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        FileDetailsPanel              matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        FS_LABEL                      matlab.ui.control.Label
        SamplingRateLabel             matlab.ui.control.Label
        FILENAME_LABEL                matlab.ui.control.Label
        FileNameLabel                 matlab.ui.control.Label
        ViewPanel                     matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        ShowRawECGCheckBox            matlab.ui.control.CheckBox
        ShowFilteredECGCheckBox       matlab.ui.control.CheckBox
        KeypressFactorEditField       matlab.ui.control.EditField
        KeypressFactorEditFieldLabel  matlab.ui.control.Label
        UpperXLimitEditField          matlab.ui.control.EditField
        UpperXLimitEditFieldLabel     matlab.ui.control.Label
        LowerXLimitEditField          matlab.ui.control.EditField
        LowerXLimitEditFieldLabel     matlab.ui.control.Label
        ECGPanel                      matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        RemoveHeartbeatsButton        matlab.ui.control.Button
        AddHeartbeatsButton           matlab.ui.control.Button
        DataRemovalPanel              matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SelectDataforRemovalButton    matlab.ui.control.Button
        EnterRemovalModeButton        matlab.ui.control.StateButton
        ECG_Axis                      matlab.ui.control.UIAxes
        RespRate_Axis                 matlab.ui.control.UIAxes
        HeartRate_Axis                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         
        %% Plot objects. We have these so that we can just edit the X/Y Data!
        ECG_Plot
        ECGRaw_Plot
        Beat_Plot
        HeartRate_Plot
        RespRate_Plot


        %% Default colors
        ECG_Settings = {'Color',[.1 .6 .6],'LineWidth',1.5};
        ECG_SettingsRaw = {'Color',[.8 .2 .9 .1],'LineWidth',1.5};
        HR_Settings = {'Color',[.3 .1 .9],'LineWidth',1.5};
        Resp_Color = {'Color','b','LineWidth',1.5};
        Beat_Settings = {'Color',[1 0 0],'MarkerSize',6,'LineStyle','None','Marker','v'};

        RemovalActivatedText
    end
    
    properties (Access = public)
        %% Main ECG object. This will act as a master object. Properties are within this.
        ECG 
        %% Flags
        HasBeatRun = 0% Has the beat detection/HR run?
        %% Cursor
        CURSOR % Cursor selection listener.
        %% Data removal properties
        RemovalRects = {} % These are where we store the rectangle objects (image.roi.rectangle class)
    end
    
    methods (Access = public)
        %% A method to plot/replot the ECG data
        function ReplotECG(app)
            
            % Check if the data is spliced
            if ~app.ECG.IsSpliced
                app.ECG_Plot.XData = app.ECG.X_Filtered;
                app.ECG_Plot.YData = app.ECG.Y_Filtered;
            else
                app.ECG_Plot.XData = app.ECG.X_Spliced;
                app.ECG_Plot.YData = app.ECG.Y_Spliced;
            end

            % Replot beats, but make sure they exist first.
            if ~isempty(app.Beat_Plot)

                if ~app.ECG.IsSpliced
                    app.Beat_Plot.XData = app.ECG.X_Filtered(app.ECG.Beats == 1);
                    app.Beat_Plot.YData = app.ECG.Y_Filtered(app.ECG.Beats == 1);
                else
                    app.Beat_Plot.XData = app.ECG.X_Spliced(app.ECG.BeatsSpliced == 1);
                    app.Beat_Plot.YData = app.ECG.Y_Spliced(app.ECG.BeatsSpliced == 1);
                end
            elseif app.HasBeatRun % Doesn't exist, plot it
                app.Beat_Plot = plot(app.ECG_Axis,app.ECG.X_Filtered(app.ECG.Beats == 1),app.ECG.Y_Filtered(app.ECG.Beats == 1),app.Beat_Settings{:});
            end

            % Same thing with the heart rate
            if ~isempty(app.HeartRate_Plot)
                if ~app.ECG.IsSpliced
                    app.HeartRate_Plot.XData = app.ECG.X_Filtered;
                    app.HeartRate_Plot.YData = app.ECG.HeartRate;
                else
                    app.HeartRate_Plot.XData = app.ECG.X_Spliced;
                    app.HeartRate_Plot.YData = app.ECG.HeartRate;
                end
            elseif app.HasBeatRun
                app.HeartRate_Plot = plot(app.HeartRate_Axis,app.ECG.X_Filtered,app.ECG.HeartRate,app.HR_Settings{:});
            end
            
        end
        
        function EnableAfterFilter(app)
            app.ShowFilteredECGCheckBox.Enable = 1;
            app.ShowRawECGCheckBox.Enable = 1;
            app.ECGRaw_Plot = plot(app.ECG_Axis,app.ECG.X_Raw, app.ECG.Y_Raw, app.ECG_SettingsRaw{:},'PickableParts','none');
            legend(app.ECG_Axis,{'Filtered','Raw'})
        end
        
        function ReplotRespRate(app)
            if ~isempty(app.RespRate_Plot)
                delete(app.RespRate_Plot);
            end
            app.RespRate_Plot = plot(app.RespRate_Axis,app.ECG.Resp_X,app.ECG.Resp_Y,app.Resp_Color{:});
        end
    end
    
    methods (Access = private)
        
        function OnMove(app,src,evt)
            evname = evt.EventName;
            switch evname
                case "MovingROI"
                    src.Position(end) = max(app.ECG.Y_Filtered) * 2;
                    src.Position(2) = min(app.ECG.Y_Filtered);
            end
        end
        
        function drawRectDetectionFcn(app,src,evt)
                axChil = app.ECG_Axis.Children; 
                chilClass = arrayfun(@(h){class(h)},axChil);
                isRect = strcmpi(chilClass, 'images.roi.Rectangle');
                isRect = isRect(1); % Check if the first entry is a rectangle
                if isRect
                    if ~isempty(axChil(1).Position)
                        % Set the position to be within the bounds of the
                        % ECG
                        axChil(1).Position(end) = max(app.ECG.Y_Filtered) * 2;
                        axChil(1).Position(2) = min(app.ECG.Y_Filtered);
                    end
                end     
        end
        
        function OnXaxisChanged(app,src,ev)
            app.LowerXLimitEditField.Value = string(app.ECG_Axis.XLim(1));
            app.UpperXLimitEditField.Value = string(app.ECG_Axis.XLim(2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.ECG_Axis,'on'); 
            linkaxes([app.ECG_Axis, app.HeartRate_Axis, app.RespRate_Axis],'x')
            % Start the event listener for the ECG Axes
            app.ECG_Axis.UserData.Listener = addlistener(app.ECG_Axis,'ChildAdded',@app.drawRectDetectionFcn);
            % Start the Cursor Listener for selecting heartbeats
            app.CURSOR = datacursormode(app.UIFigure); % Start a datacursor mode object 
            
            addpath(genpath("Resources/"));
            % dbstop if error
            %% Add keypress functions to the plots so that the user can use arrow keys instead of clicking 
            set(app.UIFigure,'KeyPressFcn',@fig_kpfcn);
            function [] = fig_kpfcn(H,E)
                ix=find(strcmp(arrayfun(@class,H.Children(1).Children,'UniformOutput',false),'matlab.ui.control.UIAxes'));
                switch E.Key
                    case 'rightarrow'
                            H.Children(1).Children(ix(1)).XLim = H.Children(1).Children(ix(1)).XLim + double(string(app.KeypressFactorEditField.Value));
                    case 'leftarrow'
                            H.Children(1).Children(ix(1)).XLim = H.Children(1).Children(ix(1)).XLim - double(string(app.KeypressFactorEditField.Value)); 
                            % Really stupid. Must convert to string and then double. 
                            % Mathworks, why would you allow the restriction of a 
                            % textbox to be only digits but then still return the
                            % value as a character array????
                end
            end

            %% Add a listener so when the user moves the plot, the X/yLim boxes change
            addlistener(app.ECG_Axis, 'XLim', 'PostSet', @app.OnXaxisChanged);
            % Add default Xlims
            app.LowerXLimitEditField.Value = string(app.ECG_Axis.XLim(1));
            app.UpperXLimitEditField.Value = string(app.ECG_Axis.YLim(2));
        end

        % Menu selected function: LoadECGMenu
        function LoadECGMenuSelected(app, event)
            %% Load the ECG object into the ECG_Class 
            [file,path] = uigetfile("*.csv");
            if file ~= 0
                app.ECG = ECG_Class(strcat(path,file));
                
                % Plot the ECG in the axes and create the object
                waitdlg = uiprogressdlg(app.UIFigure,'Message','Loading ecg...');
                % Has the user already loaded in an ECG and loading in
                % another? 
                if ~isempty(app.ECG_Plot)
                    cla(app.ECG_Axis);
                    app.ECG_Plot = plot(app.ECG_Axis,app.ECG.X_Filtered,app.ECG.Y_Filtered,app.ECG_Settings{:});
                else
                    app.ECG_Plot = plot(app.ECG_Axis,app.ECG.X_Filtered,app.ECG.Y_Filtered,app.ECG_Settings{:});
                end
                % Set the axis limits to be within the confines of the data 
                xlim(app.ECG_Axis,[min(app.ECG.X_Filtered) max(app.ECG.X_Filtered)]);
                close(waitdlg);
                % Enable things that can now be used now that the user has
                % loaded in an ECGc
                
                % Add filename to the label on the bottom right panel
                app.FILENAME_LABEL.Text =  file;
                app.FILENAME_LABEL.Tooltip = file;
                app.FS_LABEL.Text = string(app.ECG.Fs) + " Hz";
            end
        end

        % Menu selected function: CalculateHeartRateMenu
        function CalculateHeartRateMenuSelected(app, event)
            BeatDetectionDlg(app);
        end

        % Value changed function: ShowFilteredECGCheckBox
        function ShowFilteredECGCheckBoxValueChanged(app, event)
            value = app.ShowFilteredECGCheckBox.Value;
            if value == 0
                app.ECG_Plot.Color = [app.ECG_Plot.Color .1];
            elseif value == 1
                app.ECG_Plot.Color = [app.ECG_Plot.Color 1];
            end
        end

        % Menu selected function: FilterwithCWTMenu
        function FilterwithCWTMenuSelected(app, event)
            CWTBandpassApp(app);
        end

        % Value changed function: ShowRawECGCheckBox
        function ShowRawECGCheckBoxValueChanged(app, event)
            value = app.ShowRawECGCheckBox.Value;
            if value == 0
                app.ECGRaw_Plot.Color = [app.ECGRaw_Plot.Color .1];
            elseif value == 1
                app.ECGRaw_Plot.Color = [app.ECGRaw_Plot.Color 1];
            end
        end

        % Button pushed function: SelectDataforRemovalButton
        function SelectDataforRemovalButtonPushed(app, event)
            % Disable the button so the user can't click it again and bug
            % it out
            app.SelectDataforRemovalButton.Enable = 'off';
            % Create the rectangle and add the listeners
            rect = drawrectangle(app.ECG_Axis);
            addlistener(rect,"MovingROI",@app.OnMove);
            % Check if the user has canceled the draw
            if ~isempty(rect.Position)
                % Store it in the array
                app.RemovalRects{length(app.RemovalRects)+1} = rect;
            end
            % Re-enable
            app.SelectDataforRemovalButton.Enable = 'on';
        end

        % Value changed function: EnterRemovalModeButton
        function EnterRemovalModeButtonValueChanged(app, event)
            value = app.EnterRemovalModeButton.Value;
            if app.HasBeatRun == 0
                uialert(app.UIFigure,"Beat detection has not run. Please calculate heart rate first!","HR Not calculated.")
                app.EnterRemovalModeButton.Value = false;
            else
                if value == 1
                    % Reenable the select data for removal button
                    app.SelectDataforRemovalButton.Enable = 1;
                    % add text that signals that removal mode has been
                    % activated 
                    app.RemovalActivatedText = text('Parent',app.ECG_Axis,...
                        'String','REMOVAL MODE ACTIVATED', ...
                        'Units', 'normalized', ...
                        'Position',[.01 .88],...
                        'Color',[1 .1 0],...
                        'FontWeight','bold',...
                        'FontSize',15);
                    % Then enable every removal rectangle if any
                    if ~isempty(app.RemovalRects)
                        for i = 1:length(app.RemovalRects)
                           app.RemovalRects{i}.InteractionsAllowed = 'all';
                        end
                    end
                elseif value == 0
                    app.SelectDataforRemovalButton.Enable = 0;
                    delete(app.RemovalActivatedText)
                    
                    % Check if there are rects stored in the removalrects
                    % array, if there are then the data is spliced and we can
                    % re-run calculations
                    RectCheck = cellfun(@isvalid,app.RemovalRects);
                    % Delete the entries with invalid handles
                    app.RemovalRects = app.RemovalRects(RectCheck);
                    if any(RectCheck) % Yes, there are splice rectangles in the plot
                        app.ECG = app.ECG.SpliceECG(app.RemovalRects,app);
                        % Recalculate heart rate 
                        app.ECG = app.ECG.CalculateHeartRate(app.ECG.BeatsSpliced,app.ECG.X_Spliced,app.ECG.Fs);
                        app.ReplotECG;
                    else
                        app.ECG.IsSpliced = 0;
                        app.ECG = app.ECG.CalculateHeartRate(app.ECG.Beats,app.ECG.X_Filtered,app.ECG.Fs);
                        app.ReplotECG;
                    end
                    % Disable interactions on the rects
                    if ~isempty(app.RemovalRects)
                        for i = 1:length(app.RemovalRects)
                           app.RemovalRects{i}.InteractionsAllowed = 'none';
                        end
                    end
                end
            end
            
        end

        % Button pushed function: AddHeartbeatsButton
        function AddHeartbeatsButtonPushed(app, event)
            % Find index where cursor is
            cursorInfo = getCursorInfo(app.CURSOR);
            if ~isempty(cursorInfo)
                % There's a better way to do this, but because of how matlab
                % plotting works, we have to do it this way. If there is
                % nan'd/spliced data occurring before the cursor, it will
                % incorrectly find the data index. 
                for i = 1:length(cursorInfo)
                    if app.ECG.IsSpliced == 1
                        ix = find(app.ECG.X_Spliced == cursorInfo(i).Position(1));
                        app.ECG.BeatsSpliced(ix) = 1;
                        app.ECG.Beats(ix) = 1;
                        app.ECG = app.ECG.CalculateHeartRate(app.ECG.BeatsSpliced,app.ECG.X_Spliced,app.ECG.Fs);
                        app.ReplotECG;
                    else
                        ix = find(app.ECG.X_Filtered == cursorInfo(i).Position(1));
                        app.ECG.Beats(ix) = 1;
                        app.ECG = app.ECG.CalculateHeartRate(app.ECG.Beats,app.ECG.X_Filtered,app.ECG.Fs);
                        app.ReplotECG;
                    end
                end
                app.CURSOR.removeAllDataCursors(app.ECG_Axis);
            else
                uialert(app.UIFigure,"There are no points selected!","No Points Selected.")
            end
        end

        % Button pushed function: RemoveHeartbeatsButton
        function RemoveHeartbeatsButtonPushed(app, event)
            % Find index where cursor is
            cursorInfo = getCursorInfo(app.CURSOR);

            if ~isempty(cursorInfo)
                % There's a better way to do this, but because of how matlab
                % plotting works, we have to do it this way. If there is
                % nan'd/spliced data occurring before the cursor, it will
                % incorrectly find the data index. 
                for i = 1:length(cursorInfo)
                    if app.ECG.IsSpliced == 1
                        ix = find(app.ECG.X_Spliced == cursorInfo(i).Position(1));
                        app.ECG.BeatsSpliced(ix) = 0;
                        app.ECG.Beats(ix) = 0;
                        app.ECG = app.ECG.CalculateHeartRate(app.ECG.BeatsSpliced,app.ECG.X_Spliced,app.ECG.Fs);
                        app.ReplotECG;
                    else
                        ix = find(app.ECG.X_Filtered == cursorInfo(i).Position(1));
                        app.ECG.Beats(ix) = 0;
                        app.ECG = app.ECG.CalculateHeartRate(app.ECG.Beats,app.ECG.X_Filtered,app.ECG.Fs);
                        app.ReplotECG;
                    end
                end
                app.CURSOR.removeAllDataCursors(app.ECG_Axis);
            else
                uialert(app.UIFigure,"There are no points selected!","No Points Selected.")
            end
        end

        % Menu selected function: AllContentsMenu
        function AllContentsMenuSelected(app, event)
            % TODO: add check to make sure beat analysis has run 
            [file,path] = uiputfile("*.csv");
            if file ~= 0
                fullpath = strcat(path,'/',file);
                if app.ECG.IsSpliced
                    t = table();
                    x=app.ECG.X_Spliced;
                    hr=app.ECG.HeartRate;
                    bts = app.ECG.BeatsSpliced;
                    resp = app.ECG.Resp_Y;

                    if ~iscolumn(x)
                        x=x';
                    end
                    if ~iscolumn(hr)
                        hr=hr';
                    end
                    if ~iscolumn(bts)
                        bts=bts';
                    end
                    % Check if resp has been run
                    if ~isempty(resp)
                        % Check if is row
                        if ~iscolumn(resp)
                            resp = resp';
                        end
                        % Check sizes
                        if (size(resp,1) > size(x,1)) %| (size(resp,1) < size(x,1))
                            df = size(resp,1) - size(x,1);
                            resp = resp(1:end-df);
                        elseif (size(resp,1) < size(x,1))
                            df = size(x,1) - size(resp,1);
                            resp = [resp; nan(df,1)];
                        end
                    end

                    t.Time = x;
                    t.HeartRate = hr;
                    t.Beats = bts;

                    if ~isempty(resp)
                    t.Resp = resp;
                    end
                    
                    
                    writetable(t,fullpath)
                else
                    t = table();
                    x=app.ECG.X_Filtered;
                    hr=app.ECG.HeartRate;
                    bts = app.ECG.Beats;
                    resp = app.ECG.Resp_Y;

                    if ~iscolumn(x)
                        x=x';
                    end
                    if ~iscolumn(hr)
                        hr=hr';
                    end
                    if ~iscolumn(bts)
                        bts=bts';
                    end
                    % Check if resp has been run
                    if ~isempty(resp)
                        % Check if is row
                        if ~iscolumn(resp)
                            resp = resp';
                        end
                        % Check sizes
                        if (size(resp,1) > size(x,1)) %| (size(resp,1) < size(x,1))
                            df = size(resp,1) - size(x,1);
                            resp = resp(1:end-df);
                        elseif (size(resp,1) < size(x,1))
                            df = size(x,1) - size(resp,1);
                            resp = [resp; nan(df,1)];
                        end
                    end
                    
                    t.Time = x;
                    t.HeartRate = hr;
                    t.Beats = bts;

                    if ~isempty(resp)
                        t.Resp = resp;
                    end

                    writetable(t,fullpath)
                end
            end
        end

        % Menu selected function: ExtractRespiratorySignalMenu
        function ExtractRespiratorySignalMenuSelected(app, event)
            RespDLG(app);
        end

        % Value changed function: LowerXLimitEditField
        function LowerXLimitEditFieldValueChanged(app, event)
            value = double(string(app.LowerXLimitEditField.Value));
            app.ECG_Axis.XLim(1) = double(string(value));
        end

        % Value changed function: UpperXLimitEditField
        function UpperXLimitEditFieldValueChanged(app, event)
            value = app.UpperXLimitEditField.Value;
            app.ECG_Axis.XLim(2) = double(string(value));
        end

        % Menu selected function: SaveProjectFileMenu
        function SaveProjectFileMenuSelected(app, event)
            % loading a project requires a few things, so this is what we
            % should save: 
            % 1. Entire ECG Class Object
            % 2. Removal Indices (via the Rectangle objects, stored in
            %    removalrects)
            savestruct = struct;
            savestruct.ECG = struct(app.ECG);
            savestruct.Rects = cellfun(@(x)(x.Position),app.RemovalRects,'UniformOutput',false);
            jsonfile = jsonencode(savestruct);
            [file,path] = uiputfile(".Flow");

            if file ~= 0
                JSONFILE_name = strcat(path,'/',file);
                fid=fopen(JSONFILE_name,'w');
                fprintf(fid,jsonfile);
                fclose(fid);
            end
        end

        % Menu selected function: LoadProjectFileMenu
        function LoadProjectFileMenuSelected(app, event)
            % First read the .Flow file 
            [file,path] = uigetfile("*.Flow");
            if file ~= 0
                if ~contains(file,".Flow")
                    uialert("This is not a valid .Flow file!");
                else
                    % Maybe set an alert to alert the user that loading a new
                    % file will destroy unsaved changes. TODO. 
    
                    % Read in the .Flow file
                    js = jsondecode(fileread(strcat(path,'/',file)));
                    app.ECG = ECG_Class(js.ECG);
                    % For some reason decoding the JSON file transposes the
                    % beats. No idea why. But this is where I fix it.
                    app.ECG.Beats = app.ECG.Beats';
                    app.ECG.BeatsSpliced = app.ECG.BeatsSpliced';

                    % Clear the plots and reset the view 
                    cla(app.ECG_Axis);
                    cla(app.HeartRate_Axis);
                    cla(app.RespRate_Axis);
                    % Define new plot objects 
                    app.ECG_Plot = line('Parent',app.ECG_Axis,app.ECG_Settings{:});
                    app.HeartRate_Plot = line('Parent',app.HeartRate_Axis,app.HR_Settings{:});
                    app.RespRate_Plot = line('Parent',app.RespRate_Axis,app.Resp_Color{:});
                    app.Beat_Plot = line('Parent',app.ECG_Axis,app.Beat_Settings{:});
                    % If there is HR in the new ECG ax, set app.HasBeatRun to
                    % 1;
                    if ~isempty(app.ECG.HeartRate)
                        app.HasBeatRun = 1;

                        if app.ECG.HasFiltered
                            app.EnableAfterFilter;
                        end
                    else
                        app.HasBeatRun = 0;
                        % Disable things that should be disabled before
                        % beat detection has run
                        app.EnterRemovalModeButton.Enable = 0;

                    end
                    % Replot things
                    app.ReplotECG;
                    app.ReplotRespRate;
                    % If there are removal rectangles in the save file,
                    % loop through them and place them in
                    if ~isempty(js.Rects)
                        for i = 1:size(js.Rects,1)
                            app.RemovalRects{i} = images.roi.Rectangle('Position',js.Rects(i,:),'Parent',app.ECG_Axis,'InteractionsAllowed','none');
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TonaFlow';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File...';
            app.LoadProjectFileMenu = uimenu(app.FileMenu);
            app.LoadProjectFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectFileMenuSelected, true);
            app.LoadProjectFileMenu.Text = 'Load Project File';
            app.SaveProjectFileMenu = uimenu(app.FileMenu);
            app.SaveProjectFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectFileMenuSelected, true);
            app.SaveProjectFileMenu.Text = 'Save Project File';
            app.LoadECGMenu = uimenu(app.FileMenu);
            app.LoadECGMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadECGMenuSelected, true);
            app.LoadECGMenu.Text = 'Load ECG';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export...';
            app.AllContentsMenu = uimenu(app.ExportMenu);
            app.AllContentsMenu.MenuSelectedFcn = createCallbackFcn(app, @AllContentsMenuSelected, true);
            app.AllContentsMenu.Text = 'All Contents';
            app.ECGMenu = uimenu(app.UIFigure);
            app.ECGMenu.Text = 'ECG...';
            app.FilterwithCWTMenu = uimenu(app.ECGMenu);
            app.FilterwithCWTMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterwithCWTMenuSelected, true);
            app.FilterwithCWTMenu.Text = 'Filter with CWT';
            app.ExtractRespiratorySignalMenu = uimenu(app.ECGMenu);
            app.ExtractRespiratorySignalMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractRespiratorySignalMenuSelected, true);
            app.ExtractRespiratorySignalMenu.Text = 'Extract Respiratory Signal';
            app.CalculateHeartRateMenu = uimenu(app.ECGMenu);
            app.CalculateHeartRateMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateHeartRateMenuSelected, true);
            app.CalculateHeartRateMenu.Text = 'Calculate Heart Rate';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HeartRate_Axis = uiaxes(app.GridLayout);
            title(app.HeartRate_Axis, 'Heart Rate')
            xlabel(app.HeartRate_Axis, 'Time')
            ylabel(app.HeartRate_Axis, 'Y')
            zlabel(app.HeartRate_Axis, 'Z')
            app.RespRate_Axis = uiaxes(app.GridLayout);
            title(app.RespRate_Axis, 'Respiration Rate')
            xlabel(app.RespRate_Axis, 'X')
            ylabel(app.RespRate_Axis, 'Y')
            zlabel(app.RespRate_Axis, 'Z')
            app.ECG_Axis = uiaxes(app.GridLayout);
            title(app.ECG_Axis, 'ECG')
            xlabel(app.ECG_Axis, 'Time')
            ylabel(app.ECG_Axis, 'Y')
            zlabel(app.ECG_Axis, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.DataRemovalPanel = uipanel(app.GridLayout2);
            app.DataRemovalPanel.Title = 'Data Removal';
            app.GridLayout3 = uigridlayout(app.DataRemovalPanel);
            app.EnterRemovalModeButton = uibutton(app.GridLayout3, 'state');
            app.EnterRemovalModeButton.ValueChangedFcn = createCallbackFcn(app, @EnterRemovalModeButtonValueChanged, true);
            app.EnterRemovalModeButton.Text = 'Enter Removal Mode';
            app.SelectDataforRemovalButton = uibutton(app.GridLayout3, 'push');
            app.SelectDataforRemovalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataforRemovalButtonPushed, true);
            app.SelectDataforRemovalButton.Enable = 'off';
            app.SelectDataforRemovalButton.Text = 'Select Data for Removal';
            app.ECGPanel = uipanel(app.GridLayout2);
            app.ECGPanel.Title = 'ECG';
            app.GridLayout4 = uigridlayout(app.ECGPanel);
            app.AddHeartbeatsButton = uibutton(app.GridLayout4, 'push');
            app.AddHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @AddHeartbeatsButtonPushed, true);
            app.AddHeartbeatsButton.Text = 'Add Heartbeat(s)';
            app.RemoveHeartbeatsButton = uibutton(app.GridLayout4, 'push');
            app.RemoveHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveHeartbeatsButtonPushed, true);
            app.RemoveHeartbeatsButton.Text = 'Remove Heartbeat(s)';
            app.ViewPanel = uipanel(app.GridLayout2);
            app.ViewPanel.Title = 'View';
            app.GridLayout5 = uigridlayout(app.ViewPanel);
            app.LowerXLimitEditFieldLabel = uilabel(app.GridLayout5);
            app.LowerXLimitEditFieldLabel.Text = 'Lower X Limit';
            app.LowerXLimitEditField = uieditfield(app.GridLayout5, 'text');
            app.LowerXLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerXLimitEditFieldValueChanged, true);
            app.UpperXLimitEditFieldLabel = uilabel(app.GridLayout5);
            app.UpperXLimitEditFieldLabel.Text = 'Upper X Limit';
            app.UpperXLimitEditField = uieditfield(app.GridLayout5, 'text');
            app.UpperXLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperXLimitEditFieldValueChanged, true);
            app.KeypressFactorEditFieldLabel = uilabel(app.GridLayout5);
            app.KeypressFactorEditFieldLabel.Text = 'Keypress Factor';
            app.KeypressFactorEditField = uieditfield(app.GridLayout5, 'text');
            app.KeypressFactorEditField.InputType = 'digits';
            app.KeypressFactorEditField.Value = '5';
            app.ShowFilteredECGCheckBox = uicheckbox(app.GridLayout5);
            app.ShowFilteredECGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFilteredECGCheckBoxValueChanged, true);
            app.ShowFilteredECGCheckBox.Enable = 'off';
            app.ShowFilteredECGCheckBox.Text = 'Show Filtered ECG';
            app.ShowFilteredECGCheckBox.Value = true;
            app.ShowRawECGCheckBox = uicheckbox(app.GridLayout5);
            app.ShowRawECGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawECGCheckBoxValueChanged, true);
            app.ShowRawECGCheckBox.Enable = 'off';
            app.ShowRawECGCheckBox.Text = 'Show Raw ECG';
            app.FileDetailsPanel = uipanel(app.GridLayout2);
            app.FileDetailsPanel.Title = 'File Details';
            app.GridLayout6 = uigridlayout(app.FileDetailsPanel);
            app.FileNameLabel = uilabel(app.GridLayout6);
            app.FileNameLabel.Text = 'File Name:';
            app.FILENAME_LABEL = uilabel(app.GridLayout6);
            app.FILENAME_LABEL.Text = '';
            app.SamplingRateLabel = uilabel(app.GridLayout6);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.FS_LABEL = uilabel(app.GridLayout6);
            app.FS_LABEL.Text = '';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'TF Logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end